classdef DoublePendulumCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LenghtofString1mEditFieldLabel  matlab.ui.control.Label
        LenghtofString1mEditField       matlab.ui.control.NumericEditField
        LengthofString2mEditFieldLabel  matlab.ui.control.Label
        LengthofString2mEditField       matlab.ui.control.NumericEditField
        Mass1kgEditFieldLabel           matlab.ui.control.Label
        Mass1kgEditField                matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        Mass2kgEditFieldLabel           matlab.ui.control.Label
        Mass2kgEditField                matlab.ui.control.NumericEditField
        AcceleratoinDuetoGravityms2EditFieldLabel  matlab.ui.control.Label
        AcceleratoinDuetoGravityms2EditField  matlab.ui.control.NumericEditField
        ForceCoefficient1kgms2EditFieldLabel  matlab.ui.control.Label
        ForceCoefficient1kgms2EditField  matlab.ui.control.NumericEditField
        ForceCoefficient2kgms2EditFieldLabel  matlab.ui.control.Label
        ForceCoefficient2kgms2EditField  matlab.ui.control.NumericEditField
        ForceFrequency1radsEditFieldLabel  matlab.ui.control.Label
        ForceFrequency1radsEditField    matlab.ui.control.NumericEditField
        ForceFrequency2radsEditFieldLabel  matlab.ui.control.Label
        ForceFrequency2radsEditField    matlab.ui.control.NumericEditField
        InitialAngle1DegreesSliderLabel  matlab.ui.control.Label
        InitialAngle1DegreesSlider      matlab.ui.control.Slider
        InitialAngularVelocity1radsEditFieldLabel  matlab.ui.control.Label
        InitialAngularVelocity1radsEditField  matlab.ui.control.NumericEditField
        InitialAngularVelocity2radsEditFieldLabel  matlab.ui.control.Label
        InitialAngularVelocity2radsEditField  matlab.ui.control.NumericEditField
        InitialAngle2DegreesSliderLabel  matlab.ui.control.Label
        InitialAngle2DegreesSlider      matlab.ui.control.Slider
        Image                           matlab.ui.control.Image
        DoublePendulumLabel             matlab.ui.control.Label
        CalclulatorLabel                matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_2       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {765, 765};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {336, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Credit to Mathworks open code for inital set up       
            % Define Displacement, Velocity, and Acceleration of Double Pendulum Masses
            syms theta_1(t) theta_2(t) L_1 L_2 m_1 m_2 g
            
            x_1 = L_1*sin(theta_1);
            y_1 = -L_1*cos(theta_1);
            x_2 = x_1 + L_2*sin(theta_2);
            y_2 = y_1 - L_2*cos(theta_2);
            
            vx_1 = diff(x_1);
            vy_1 = diff(y_1);
            vx_2 = diff(x_2);
            vy_2 = diff(y_2);
            
            ax_1 = diff(vx_1);
            ay_1 = diff(vy_1);
            ax_2 = diff(vx_2);
            ay_2 = diff(vy_2);
            
            % Define Equations of Motion
            syms T_1 T_2 F_1 Om_1 F_2 Om_2 t
            
            eqx_1 = m_1*ax_1(t) == -T_1*sin(theta_1(t)) + T_2*sin(theta_2(t)) + F_1*cos(Om_1*t);
            eqy_1 = m_1*ay_1(t) == T_1*cos(theta_1(t)) - T_2*cos(theta_2(t)) - m_1*g;
            eqx_2 = m_2*ax_2(t) == -T_2*sin(theta_2(t)) + F_2*cos(Om_2*t);
            eqy_2 = m_2*ay_2(t) == T_2*cos(theta_2(t)) - m_2*g;
            
            % Evaluate Forces and Reduce System Equations
            Tension = solve([eqx_1 eqy_1],[T_1 T_2]);
            eqRed_1 = subs(eqx_2,[T_1 T_2],[Tension.T_1 Tension.T_2]);
            eqRed_2 = subs(eqy_2,[T_1 T_2],[Tension.T_1 Tension.T_2]);
            
            % Solve System Equations
            L_1 = app.LenghtofString1mEditField.Value;
            L_2 = app.LengthofString2mEditField.Value;
            m_1 = app.Mass1kgEditField.Value;
            m_2 = app.Mass2kgEditField.Value;
            g = app.AcceleratoinDuetoGravityms2EditField.Value;
            F_1 = app.ForceCoefficient1kgms2EditField.Value;
            Om_1 = app.ForceFrequency1radsEditField.Value;
            F_2 = app.ForceCoefficient2kgms2EditField.Value;
            Om_2 = app.ForceFrequency2radsEditField.Value;
            
            eqn_1 = subs(eqRed_1);
            eqn_2 = subs(eqRed_2);
            
            [V,S] = odeToVectorField(eqn_1,eqn_2);
            
            M = matlabFunction(V,'vars',{'t','Y'});
            
            theta1_0 = app.InitialAngle1DegreesSlider.Value*3.1415/180;
            omega1_0 = app.InitialAngularVelocity1radsEditField.Value;
            theta2_0 = app.InitialAngle2DegreesSlider.Value*3.1415/180;
            omega2_0 = app.InitialAngularVelocity2radsEditField.Value;
            
            initCond = [theta2_0 omega2_0 theta1_0 omega1_0];
            sols = ode45(M,[0 10],initCond);
            
            % Plot the Solutions of State Variables
            plot(app.PrincipalInterestUIAxes, sols.x, sols.y);
            legend(app.PrincipalInterestUIAxes,'\theta_2','d\theta_2/dt','\theta_1','d\theta_1/dt')
            
            % Create Animation of Oscillating Double Pendulum
            t = linspace(0,10,500);
            x_1 = L_1*sin(deval(sols,t,3));
            y_1 = -L_1*cos(deval(sols,t,3));
            x_2 = L_1*sin(deval(sols,t,3))+L_2*sin(deval(sols,t,1));
            y_2 = -L_1*cos(deval(sols,t,3))-L_2*cos(deval(sols,t,1));
            
            for i = 1:500
                plot(app.PrincipalInterestUIAxes_2, x_1(i), y_1(i),'ro','MarkerSize',m_1*10,'MarkerFaceColor','r');
                axis(app.PrincipalInterestUIAxes_2, [-L_1-L_2 L_1+L_2 -L_1-L_2 L_1+L_2]);
                hold(app.PrincipalInterestUIAxes_2, "on");
                plot(app.PrincipalInterestUIAxes_2, [0 x_1(i)],[0 y_1(i)],'r-');
                plot(app.PrincipalInterestUIAxes_2, x_2(i), y_2(i), 'go','MarkerSize',m_2*10,'MarkerFaceColor','g');
                plot(app.PrincipalInterestUIAxes_2, [x_1(i) x_2(i)],[y_1(i) y_2(i)],'g-');
                hold(app.PrincipalInterestUIAxes_2, "off");
                pause(0.01);
            end
            
            %fanimator(@(t) plot(app.PrincipalInterestUIAxes_2, x_1(t),y_1(t),'ro','MarkerSize',m_1*10,'MarkerFaceColor','r'))
            %axis(app.PrincipalInterestUIAxes_2, "equal");
            
            %hold(app.PrincipalInterestUIAxes_2, "on");
            %fanimator(app.PrincipalInterestUIAxes_2, @(t) plot([0 x_1(t)],[0 y_1(t)],'r-'));
            %fanimator(app.PrincipalInterestUIAxes_2, @(t) plot(x_2(t),y_2(t),'go','MarkerSize',m_2*10,'MarkerFaceColor','g'));
            %fanimator(app.PrincipalInterestUIAxes_2, @(t) plot([x_1(t) x_2(t)],[y_1(t) y_2(t)],'g-'));
            
            %fanimator(app.PrincipalInterestUIAxes_2, @(t) text(-0.3,0.3,"Timer: "+num2str(t,2)));
            %hold(app.PrincipalInterestUIAxes_2, "off");
            %playAnimation;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LenghtofString1mEditFieldLabel = uilabel(app.LeftPanel);
            app.LenghtofString1mEditFieldLabel.Text = 'Lenght of String 1 (m)';
            app.LenghtofString1mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LenghtofString1mEditField.Limits = [0 10000000];
            app.LenghtofString1mEditField.ValueDisplayFormat = '%8.f';
            app.LenghtofString1mEditField.Value = 1;
            app.LengthofString2mEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthofString2mEditFieldLabel.Text = 'Length of String 2 (m)';
            app.LengthofString2mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthofString2mEditField.Limits = [0 10000000];
            app.LengthofString2mEditField.Value = 1.5;
            app.Mass1kgEditFieldLabel = uilabel(app.LeftPanel);
            app.Mass1kgEditFieldLabel.Text = 'Mass 1 (kg)';
            app.Mass1kgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Mass1kgEditField.Limits = [0 10000000];
            app.Mass1kgEditField.ValueDisplayFormat = '%.0f';
            app.Mass1kgEditField.Value = 2;
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Mass2kgEditFieldLabel = uilabel(app.LeftPanel);
            app.Mass2kgEditFieldLabel.Text = 'Mass 2 (kg)';
            app.Mass2kgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Mass2kgEditField.Limits = [0 10000000];
            app.Mass2kgEditField.ValueDisplayFormat = '%.0f';
            app.Mass2kgEditField.Value = 1;
            app.AcceleratoinDuetoGravityms2EditFieldLabel = uilabel(app.LeftPanel);
            app.AcceleratoinDuetoGravityms2EditFieldLabel.Text = 'Acceleratoin Due to Gravity (m/s^2)';
            app.AcceleratoinDuetoGravityms2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.AcceleratoinDuetoGravityms2EditField.Limits = [0 10000000];
            app.AcceleratoinDuetoGravityms2EditField.ValueDisplayFormat = '%.0f';
            app.AcceleratoinDuetoGravityms2EditField.Value = 9.8;
            app.ForceCoefficient1kgms2EditFieldLabel = uilabel(app.LeftPanel);
            app.ForceCoefficient1kgms2EditFieldLabel.Text = 'Force Coefficient 1 (kg*m/s^2)';
            app.ForceCoefficient1kgms2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.ForceCoefficient1kgms2EditField.ValueDisplayFormat = '%.0f';
            app.ForceCoefficient1kgms2EditField.Value = 5;
            app.ForceCoefficient2kgms2EditFieldLabel = uilabel(app.LeftPanel);
            app.ForceCoefficient2kgms2EditFieldLabel.Text = 'Force Coefficient 2 (kg*m/s^2)';
            app.ForceCoefficient2kgms2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.ForceCoefficient2kgms2EditField.ValueDisplayFormat = '%.0f';
            app.ForceCoefficient2kgms2EditField.Value = 5;
            app.ForceFrequency1radsEditFieldLabel = uilabel(app.LeftPanel);
            app.ForceFrequency1radsEditFieldLabel.Text = 'Force Frequency 1 (rad/s)';
            app.ForceFrequency1radsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ForceFrequency1radsEditField.Limits = [0 10000000];
            app.ForceFrequency1radsEditField.ValueDisplayFormat = '%.0f';
            app.ForceFrequency1radsEditField.Value = 2;
            app.ForceFrequency2radsEditFieldLabel = uilabel(app.LeftPanel);
            app.ForceFrequency2radsEditFieldLabel.Text = 'Force Frequency 2 (rad/s)';
            app.ForceFrequency2radsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ForceFrequency2radsEditField.Limits = [0 10000000];
            app.ForceFrequency2radsEditField.ValueDisplayFormat = '%.0f';
            app.ForceFrequency2radsEditField.Value = 2;
            app.InitialAngle1DegreesSliderLabel = uilabel(app.LeftPanel);
            app.InitialAngle1DegreesSliderLabel.Text = 'Initial Angle 1 (Degrees)';
            app.InitialAngle1DegreesSlider = uislider(app.LeftPanel);
            app.InitialAngle1DegreesSlider.Limits = [0 360];
            app.InitialAngularVelocity1radsEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialAngularVelocity1radsEditFieldLabel.Text = 'Initial Angular Velocity 1 (rad/s)';
            app.InitialAngularVelocity1radsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialAngularVelocity1radsEditField.ValueDisplayFormat = '%.0f';
            app.InitialAngularVelocity2radsEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialAngularVelocity2radsEditFieldLabel.Text = 'Initial Angular Velocity 2 (rad/s)';
            app.InitialAngularVelocity2radsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialAngularVelocity2radsEditField.ValueDisplayFormat = '%.0f';
            app.InitialAngle2DegreesSliderLabel = uilabel(app.LeftPanel);
            app.InitialAngle2DegreesSliderLabel.Text = 'Initial Angle 2 (Degrees)';
            app.InitialAngle2DegreesSlider = uislider(app.LeftPanel);
            app.InitialAngle2DegreesSlider.Limits = [0 360];
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'IMG_0320.jpg';
            app.DoublePendulumLabel = uilabel(app.LeftPanel);
            app.DoublePendulumLabel.Text = 'Double Pendulum';
            app.CalclulatorLabel = uilabel(app.LeftPanel);
            app.CalclulatorLabel.Text = 'Calclulator';
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Solutions of State Variables')
            xlabel(app.PrincipalInterestUIAxes, 'Time (s)')
            ylabel(app.PrincipalInterestUIAxes, 'Solutions (rad or rad/s)')
            app.PrincipalInterestUIAxes_2 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_2, 'Animation of Double Pendulum Motion')
            xlabel(app.PrincipalInterestUIAxes_2, 'X Position (m)')
            ylabel(app.PrincipalInterestUIAxes_2, 'Y Position (m)')
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoublePendulumCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end